classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalibrationMethodDropDownLabel  matlab.ui.control.Label
        CalibrationMethodDropDown       matlab.ui.control.DropDown
        ResponseDataCountPanel          matlab.ui.container.Panel
        SurfacePotentialSpinnerLabel    matlab.ui.control.Label
        SurfacePotentialSpinner         matlab.ui.control.Spinner
        NormalCurrentdensitySpinnerLabel  matlab.ui.control.Label
        NormalCurrentdensitySpinner     matlab.ui.control.Spinner
        ElectricFieldSpinnerLabel       matlab.ui.control.Label
        ElectricFieldSpinner            matlab.ui.control.Spinner
        ResponsedataIDsPanel            matlab.ui.container.Panel
        SurfacePotentialEditFieldLabel  matlab.ui.control.Label
        SurfacePotentialEditField       matlab.ui.control.EditField
        NormalCurrentdensityEditFieldLabel  matlab.ui.control.Label
        NormalCurrentdensityEditField   matlab.ui.control.EditField
        ElectricFieldEditFieldLabel     matlab.ui.control.Label
        ElectricFieldEditField          matlab.ui.control.EditField
        ParametersforcalibrationPanel   matlab.ui.container.Panel
        polarisationcurvepvalueEditFieldLabel  matlab.ui.control.Label
        polarisationcurvepvalueEditField  matlab.ui.control.EditField
        ConductivityEditFieldLabel      matlab.ui.control.Label
        ConductivityEditField           matlab.ui.control.EditField
        BEASYinputoutputresourcesPanel  matlab.ui.container.Panel
        rootfolderEditFieldLabel        matlab.ui.control.Label
        rootfolderEditField             matlab.ui.control.EditField
        collectiondirEditFieldLabel     matlab.ui.control.Label
        collectiondirEditField          matlab.ui.control.EditField
        GraphDisplayDropDown_2Label     matlab.ui.control.Label
        GraphDisplayDropDown_2          matlab.ui.control.DropDown
        ParameterslowerrangeEditFieldLabel  matlab.ui.control.Label
        ParameterslowerrangeEditField   matlab.ui.control.EditField
        ParametersupperrangeEditFieldLabel  matlab.ui.control.Label
        ParametersupperrangeEditField   matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        CalibrationdataPanel            matlab.ui.container.Panel
        filepathEditFieldLabel          matlab.ui.control.Label
        filepathEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
       parameters = {};
        % Description
       calibration_data_type = {}; % Description
       metric = 'nmsq';
       IDs = {};
       IDs_types = {};
       DOE_sample_points;
       surrogates;
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: SurfacePotentialSpinner
        function SurfacePotentialSpinnerValueChanged(app, event)
            value = app.SurfacePotentialSpinner.Value;
            if ~isequal(value, 0)
                app.calibration_data_type = {'voltage'};
                app.SurfacePotentialEditField.Enable ='On';
                app.IDs_types = [app.IDs_types, {'Internal Points'}];
            end
            app.NormalCurrentdensitySpinner.Enable = 'On';
            
        end

        % Value changed function: NormalCurrentdensitySpinner
        function NormalCurrentdensitySpinnerValueChanged(app, event)
            value = app.NormalCurrentdensitySpinner.Value;
            if ~isequal(value, 0)
              
                app.calibration_data_type = [app.calibration_data_type, {'normal current density'}];
                app.IDs_types = [app.IDs_types, {'Mesh Points'}];

                app.NormalCurrentdensityEditField.Enable = 'On';
            end
            app.ElectricFieldSpinner.Enable = 'On';
            
        end

        % Value changed function: ElectricFieldSpinner
        function ElectricFieldSpinnerValueChanged(app, event)
            value = app.ElectricFieldSpinner.Value;
            if ~isequal(value, 0)
               
                app.calibration_data_type = [app.calibration_data_type, {'electric field'}];
               
                app.ElectricFieldEditField.Enable = 'On';
                
                app.IDs_types = [app.IDs_types, {'Element Points'}];
                
            end
            
        end

        % Value changed function: polarisationcurvepvalueEditField
        function polarisationcurvepvalueEditFieldValueChanged(app, event)
            value = app.polarisationcurvepvalueEditField.Value;
            if ~strcmp(value, '')
                app.parameters = [app.parameters, cellstr(strsplit(value, ' '))];
            %disp(app.parameters);
            end

        end

        % Value changed function: ConductivityEditField
        function ConductivityEditFieldValueChanged(app, event)
            value = app.ConductivityEditField.Value;
            if ~strcmp(value, '')
                app.parameters = [app.parameters, cellstr(strsplit(value, ' '))];
            %disp(app.parameters);
            end
            
        end

        % Value changed function: SurfacePotentialEditField
        function SurfacePotentialEditFieldValueChanged(app, event)
            value = app.SurfacePotentialEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
                %disp(app.IDs{1});
            end
        end

        % Value changed function: NormalCurrentdensityEditField
        function NormalCurrentdensityEditFieldValueChanged(app, event)
            value = app.NormalCurrentdensityEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
                disp(app.IDs);
            end
        end

        % Value changed function: ElectricFieldEditField
        function ElectricFieldEditFieldValueChanged(app, event)
            value = app.ElectricFieldEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %taking parameters name and their range 
            if ~strcmp(app.polarisationcurvepvalueEditField, '')
                app.parameters = [app.parameters, app.polarisationcurvepvalueEditField];
            end
            if ~strcmp(app.ConductivityEditField, '')
                app.parameters = [app.parameters, app.ConductivityEditField];
            end
            
            DOE_range = zeros(size(app.parameters), 2);
            DOE_range(:,1) = str2double(app.ParameterslowerrangeEditField);
            DOE_range(:,2) = str2double(app.ParametersupperrangeEditField);
            
            Central_composite_points = ccdesign(2, 'type', 'inscribed', 'center' , 4);

            app.DOE_sample_points = reverse_normalization(Central_composite_points, DOE_range);
            
            parameters_np_array = convert_arr_to_python_2d_list(app.DOE_sample_points);

            snapshots_py = py.BEASY_IN_OUT1.snapshots_for_given_parameters_and_IDs(py.list(app.parameters), parameters_np_array, py.list(app.IDs), py.list(app.calibration_data_type), app.rootfolderEditField, app.collectiondirEditField, py.list(IDs_types));

            snapshots = double(snapshots_py);

            app.surrogates = response_surface(DOE_sample_points1, snapshots);
            
            
        end

        % Value changed function: CalibrationMethodDropDown
        function CalibrationMethodDropDownValueChanged(app, event)
            value = app.CalibrationMethodDropDown.Value;
            I = find(app.Calibratio)
            if strcmp(value, '')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalibrationMethodDropDownLabel = uilabel(app.UIFigure);
            app.CalibrationMethodDropDownLabel.Text = 'Calibration Method';
            app.CalibrationMethodDropDown = uidropdown(app.UIFigure);
            app.CalibrationMethodDropDown.Items = {'gradient-based and simulator running', 'Surrogate assisted ', 'ROM assisted'};
            app.CalibrationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationMethodDropDownValueChanged, true);
            app.CalibrationMethodDropDown.Value = 'gradient-based and simulator running';
            app.ResponseDataCountPanel = uipanel(app.UIFigure);
            app.ResponseDataCountPanel.Title = 'Response Data Count';
            app.SurfacePotentialSpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.SurfacePotentialSpinnerLabel.Text = 'Surface Potential';
            app.SurfacePotentialSpinner = uispinner(app.ResponseDataCountPanel);
            app.SurfacePotentialSpinner.ValueChangedFcn = createCallbackFcn(app, @SurfacePotentialSpinnerValueChanged, true);
            app.NormalCurrentdensitySpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.NormalCurrentdensitySpinnerLabel.Text = 'Normal Current density';
            app.NormalCurrentdensitySpinner = uispinner(app.ResponseDataCountPanel);
            app.NormalCurrentdensitySpinner.Limits = [0 100];
            app.NormalCurrentdensitySpinner.ValueChangedFcn = createCallbackFcn(app, @NormalCurrentdensitySpinnerValueChanged, true);
            app.NormalCurrentdensitySpinner.Enable = 'off';
            app.ElectricFieldSpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.ElectricFieldSpinnerLabel.Text = 'Electric Field';
            app.ElectricFieldSpinner = uispinner(app.ResponseDataCountPanel);
            app.ElectricFieldSpinner.Limits = [0 100];
            app.ElectricFieldSpinner.ValueChangedFcn = createCallbackFcn(app, @ElectricFieldSpinnerValueChanged, true);
            app.ElectricFieldSpinner.Enable = 'off';
            app.ResponsedataIDsPanel = uipanel(app.UIFigure);
            app.ResponsedataIDsPanel.Title = 'Response data IDs';
            app.SurfacePotentialEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.SurfacePotentialEditFieldLabel.Enable = 'off';
            app.SurfacePotentialEditFieldLabel.Text = 'Surface Potential';
            app.SurfacePotentialEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.SurfacePotentialEditField.ValueChangedFcn = createCallbackFcn(app, @SurfacePotentialEditFieldValueChanged, true);
            app.SurfacePotentialEditField.Enable = 'off';
            app.NormalCurrentdensityEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.NormalCurrentdensityEditFieldLabel.Enable = 'off';
            app.NormalCurrentdensityEditFieldLabel.Text = 'Normal Current density';
            app.NormalCurrentdensityEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.NormalCurrentdensityEditField.ValueChangedFcn = createCallbackFcn(app, @NormalCurrentdensityEditFieldValueChanged, true);
            app.NormalCurrentdensityEditField.Enable = 'off';
            app.ElectricFieldEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.ElectricFieldEditFieldLabel.Enable = 'off';
            app.ElectricFieldEditFieldLabel.Text = 'Electric Field';
            app.ElectricFieldEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.ElectricFieldEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricFieldEditFieldValueChanged, true);
            app.ElectricFieldEditField.Enable = 'off';
            app.ParametersforcalibrationPanel = uipanel(app.UIFigure);
            app.ParametersforcalibrationPanel.Title = 'Parameters for calibration';
            app.polarisationcurvepvalueEditFieldLabel = uilabel(app.ParametersforcalibrationPanel);
            app.polarisationcurvepvalueEditFieldLabel.Text = 'polarisation curve p-value ';
            app.polarisationcurvepvalueEditField = uieditfield(app.ParametersforcalibrationPanel, 'text');
            app.polarisationcurvepvalueEditField.ValueChangedFcn = createCallbackFcn(app, @polarisationcurvepvalueEditFieldValueChanged, true);
            app.ConductivityEditFieldLabel = uilabel(app.ParametersforcalibrationPanel);
            app.ConductivityEditFieldLabel.Text = 'Conductivity';
            app.ConductivityEditField = uieditfield(app.ParametersforcalibrationPanel, 'text');
            app.ConductivityEditField.ValueChangedFcn = createCallbackFcn(app, @ConductivityEditFieldValueChanged, true);
            app.BEASYinputoutputresourcesPanel = uipanel(app.UIFigure);
            app.BEASYinputoutputresourcesPanel.Title = 'BEASY input-output resources';
            app.rootfolderEditFieldLabel = uilabel(app.BEASYinputoutputresourcesPanel);
            app.rootfolderEditFieldLabel.Text = 'root folder';
            app.rootfolderEditField = uieditfield(app.BEASYinputoutputresourcesPanel, 'text');
            app.collectiondirEditFieldLabel = uilabel(app.BEASYinputoutputresourcesPanel);
            app.collectiondirEditFieldLabel.Text = 'collection dir';
            app.collectiondirEditField = uieditfield(app.BEASYinputoutputresourcesPanel, 'text');
            app.GraphDisplayDropDown_2Label = uilabel(app.UIFigure);
            app.GraphDisplayDropDown_2Label.Text = 'Graph Display';
            app.GraphDisplayDropDown_2 = uidropdown(app.UIFigure);
            app.GraphDisplayDropDown_2.Items = {'Structural model', 'Surrogate based graph', 'polarisation curve'};
            app.GraphDisplayDropDown_2.Value = 'Structural model';
            app.ParameterslowerrangeEditFieldLabel = uilabel(app.UIFigure);
            app.ParameterslowerrangeEditFieldLabel.Text = 'Parameters lower range';
            app.ParameterslowerrangeEditField = uieditfield(app.UIFigure, 'text');
            app.ParametersupperrangeEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersupperrangeEditFieldLabel.Text = 'Parameters upper range';
            app.ParametersupperrangeEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.CalibrationdataPanel = uipanel(app.UIFigure);
            app.CalibrationdataPanel.Title = 'Calibration data ';
            app.filepathEditFieldLabel = uilabel(app.CalibrationdataPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.CalibrationdataPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end