classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalibrationMethodDropDownLabel  matlab.ui.control.Label
        CalibrationMethodDropDown       matlab.ui.control.DropDown
        ResponseDataCountPanel          matlab.ui.container.Panel
        SurfacePotentialSpinnerLabel    matlab.ui.control.Label
        SurfacePotentialSpinner         matlab.ui.control.Spinner
        NormalCurrentdensitySpinnerLabel  matlab.ui.control.Label
        NormalCurrentdensitySpinner     matlab.ui.control.Spinner
        ElectricFieldSpinnerLabel       matlab.ui.control.Label
        ElectricFieldSpinner            matlab.ui.control.Spinner
        ResponsedataIDsPanel            matlab.ui.container.Panel
        SurfacePotentialEditFieldLabel  matlab.ui.control.Label
        SurfacePotentialEditField       matlab.ui.control.EditField
        NormalCurrentdensityEditFieldLabel  matlab.ui.control.Label
        NormalCurrentdensityEditField   matlab.ui.control.EditField
        ElectricFieldEditFieldLabel     matlab.ui.control.Label
        ElectricFieldEditField          matlab.ui.control.EditField
        ParametersforcalibrationPanel   matlab.ui.container.Panel
        polarisationcurvepvalueEditFieldLabel  matlab.ui.control.Label
        polarisationcurvepvalueEditField  matlab.ui.control.EditField
        ConductivityEditFieldLabel      matlab.ui.control.Label
        ConductivityEditField           matlab.ui.control.EditField
        BEASYinputoutputresourcesPanel  matlab.ui.container.Panel
        rootfolderEditFieldLabel        matlab.ui.control.Label
        rootfolderEditField             matlab.ui.control.EditField
        collectiondirEditFieldLabel     matlab.ui.control.Label
        collectiondirEditField          matlab.ui.control.EditField
        GraphDisplayDropDown_2Label     matlab.ui.control.Label
        GraphDisplayDropDown_2          matlab.ui.control.DropDown
        ParameterslowerrangeEditFieldLabel  matlab.ui.control.Label
        ParameterslowerrangeEditField   matlab.ui.control.EditField
        ParametersupperrangeEditFieldLabel  matlab.ui.control.Label
        ParametersupperrangeEditField   matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        RunButton                       matlab.ui.control.Button
        CalibrationdataPanel            matlab.ui.container.Panel
        filepathEditFieldLabel          matlab.ui.control.Label
        filepathEditField               matlab.ui.control.EditField
        SolutionParametersEditFieldLabel  matlab.ui.control.Label
        SolutionParametersEditField     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        parameters;
        % Description
       calibration_data_type = {}; % Description
       metric = 'nmsq';
       IDs = {};
       IDs_types = {};
       DOE_sample_points;
       surrogates;
       solution_parameters;
       sol_range;
       calibration_data
       ObjectiveGraphData;
       
    end
    
    methods (Access = private)
        
        function export_result(app)
            saveas(app.UIFigure, 'result_display.png');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: SurfacePotentialSpinner
        function SurfacePotentialSpinnerValueChanged(app, event)
            value = app.SurfacePotentialSpinner.Value;
            if ~isequal(value, 0)
                app.calibration_data_type = {'voltage'};
                app.SurfacePotentialEditField.Enable ='On';
                
                if ~any(strcmp(app.IDs_types, 'Internal Points'))
                    app.IDs_types = [app.IDs_types, {'Internal Points'}];
                end
                
            end
            app.NormalCurrentdensitySpinner.Enable = 'On';
            
        end

        % Value changed function: NormalCurrentdensitySpinner
        function NormalCurrentdensitySpinnerValueChanged(app, event)
            value = app.NormalCurrentdensitySpinner.Value;
            if ~isequal(value, 0)
                
                if ~any(strcmp(app.calibration_data_type, 'normal current density'))
                    app.calibration_data_type = [app.calibration_data_type, {'normal current density'}];
                
                    app.IDs_types = [app.IDs_types, {'Mesh Points'}];
                end                
                app.NormalCurrentdensityEditField.Enable = 'On';
            end
            app.ElectricFieldSpinner.Enable = 'On';
            
        end

        % Value changed function: ElectricFieldSpinner
        function ElectricFieldSpinnerValueChanged(app, event)
            value = app.ElectricFieldSpinner.Value;
            if ~isequal(value, 0)
                
                app.calibration_data_type = [app.calibration_data_type, {'electric field'}];
               
                app.ElectricFieldEditField.Enable = 'On';
                
                app.IDs_types = [app.IDs_types, {'Element Points'}];
                
            end
        end

        % Value changed function: polarisationcurvepvalueEditField
        function polarisationcurvepvalueEditFieldValueChanged(app, event)
            value = app.polarisationcurvepvalueEditField.Value;
            if ~strcmp(value, '')
                app.parameters = [app.parameters, cellstr(strsplit(value, ' '))];
            %disp(app.parameters);
            end
        end

        % Value changed function: ConductivityEditField
        function ConductivityEditFieldValueChanged(app, event)
            value = app.ConductivityEditField.Value;
            if ~strcmp(value, '')
                app.parameters = [app.parameters, cellstr(strsplit(value, ' '))];
            %disp(app.parameters);
            end
            
        end

        % Value changed function: SurfacePotentialEditField
        function SurfacePotentialEditFieldValueChanged(app, event)
            value = app.SurfacePotentialEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
                %disp(app.IDs{1});
            end
        end

        % Value changed function: NormalCurrentdensityEditField
        function NormalCurrentdensityEditFieldValueChanged(app, event)
            value = app.NormalCurrentdensityEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
                disp(app.IDs);
            end
        end

        % Value changed function: ElectricFieldEditField
        function ElectricFieldEditFieldValueChanged(app, event)
            value = app.ElectricFieldEditField.Value;
            if ~strcmp(value, '')
                app.IDs = [app.IDs, {py.list(str2num(value))}];
            end
        end

        % Value changed function: CalibrationMethodDropDown
        function CalibrationMethodDropDownValueChanged(app, event)
            app.RunButton.Visible = 'On';
            value = app.CalibrationMethodDropDown.Value;
            I = find(strcmp(app.CalibrationMethodDropDown.Items,value));
            if isequal(I, 2)
                app.RunButton.Text = 'Create Surrogate';
            elseif isequal(I,3)
                app.RunButton.Text = 'Create ROM';
            else
                app.RunButton.Text = 'Run Optimisation';
            end
            app.RunButton.Enable = 'On';
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'Off';
            if strcmp(app.RunButton.Text, 'Run Optimisation') && strcmp(app.CalibrationMethodDropDown.Value, 'Surrogate assisted')
                
                app.EditField.Value = 'Running surrogate based Optimisation';
                pause(0.01);
                
                %IDs = {str2num(app.SurfacePotentialEditField.Value), str2num(app.NormalCurrentdensityEditField.Value)};
                IDs = {str2num(app.SurfacePotentialEditField.Value)};
                %disp(IDs);
                
                app.calibration_data = data_from_tables(app.filepathEditField.Value,IDs ,3);
                

                [app.ObjectiveGraphData, ~, app.solution_parameters] = plot_objective_with_surrogates(app.UIAxes, app.sol_range, app.surrogates, app.calibration_data, 'nmsq', app.calibration_data_type, [length(app.IDs{1})],[0.5,0.5], [[0.0005,0.00005]]);
                app.EditField.Value = 'solution reached';
                pause(0.01);
                
                %disp(app.solution_parameters);
                solution_display = '';
                for i = 1:size(app.solution_parameters,2)
                    solution_display = strcat(solution_display, string(app.solution_parameters(1,i)), '_');
                end
                disp(solution_display);
                %set(app.SolutionParametersEditField.Visible, 'On');
                app.SolutionParametersEditField.Editable = 'Off';
                app.SolutionParametersEditField.Value = solution_display;
                pause(0.01);
                
            else
            
            %taking parameters name and their range 
            app.parameters = {};
            if ~strcmp(app.polarisationcurvepvalueEditField.Value, '')
                %cellstr(strsplit(app.polarisationcurvepvalueEditField.Value, ' '))
                app.parameters = [app.parameters, cellstr(strsplit(app.polarisationcurvepvalueEditField.Value, ' '))];
            end
            if ~strcmp(app.ConductivityEditField.Value, '')
                app.parameters = [app.parameters, cellstr(strsplit(app.ConductivityEditField.Value, ' '))];
            end
            
            %disp(app.parameters);
            %disp(app.IDs);
            
            app.sol_range = zeros(size(app.parameters,2), 2);
            app.sol_range(:,1) = str2double(strsplit(app.ParameterslowerrangeEditField.Value, ' '));
            app.sol_range(:,2) = str2double(strsplit(app.ParametersupperrangeEditField.Value, ' '));
            
            %disp(DOE_range);
            
            if strcmp(app.RunButton.Text, 'Create Surrogate') || strcmp(app.RunButton.Text, 'Create ROM')
                
                app.EditField.Value = 'Creating Surrogate';
                pause(0.01);
                
                Central_composite_points = ccdesign(2, 'type', 'inscribed', 'center' , 4);

                app.DOE_sample_points = reverse_normalization(Central_composite_points, app.sol_range);
                
                %disp(app.DOE_sample_points);
                
                parameters_np_array = convert_arr_to_python_2d_list(app.DOE_sample_points);
                %disp(parameters_np_array);

                snapshots_py = py.BEASY_IN_OUT1.snapshots_for_given_parameters_and_IDs(py.list(app.parameters), parameters_np_array, py.list(app.IDs), py.list(app.calibration_data_type), app.rootfolderEditField.Value, app.collectiondirEditField.Value, py.list(app.IDs_types));
                
                %{
                % disp_list = {py.list(app.parameters), parameters_np_array, py.list(app.IDs), py.list(app.calibration_data_type), app.rootfolderEditField.Value, app.collectiondirEditField.Value, py.list(app.IDs_types)};
                
                for i =1:size(disp_list,2)
                    disp(disp_list{i});
                end
                %}
                snapshots = double(snapshots_py);
                
                app.surrogates = response_surface(app.DOE_sample_points, snapshots);
                app.EditField.Value = 'Surrogate Created';
                
                app.RunButton.Text = 'Run Optimisation';
            end
            %{
            if strcmp(app.RunButton.Text, 'Create Surrogate')
                app.surrogates = response_surface(DOE_sample_points, snapshots);
            end
            %}
            end
            app.RunButton.Enable = 'On';
        end

        % Value changed function: GraphDisplayDropDown_2
        function GraphDisplayDropDown_2ValueChanged(app, event)
            value = app.GraphDisplayDropDown_2.Value;
            I  = find(strcmp(app.GraphDisplayDropDown_2.Items, value));
            if isequal(I, 1)
                cla(app.UIAxes,'reset');
                imshow('tank_structure.png','Parent',app.UIAxes); 
                
            elseif isequal(I,2)
                cla(app.UIAxes,'reset');
                surf(app.UIAxes, app.ObjectiveGraphData{1}, app.ObjectiveGraphData{2}, app.ObjectiveGraphData{3});
                xlabel(app.UIAxes,strcat(app.parameters{1},' p-value'));
                ylabel(app.UIAxes,app.parameters{2});
                zlabel(app.UIAxes,'Objective function (NMSE)');
                title(app.UIAxes,'Surrogate Assisted Objective function plot');
               
                colorbar;

            elseif isequal(I,3)
                solution_folder = '';
                for i = 1:length(app.parameters)
                    solution_folder =   strcat(solution_folder, app.parameters{i},'_', num2str(app.solution_parameters(i), '%.4f'));
                    if i~=length(app.parameters)
                        solution_folder = strcat(solution_folder, '_');
                    end
                end
                solution_dir = fullfile(app.collectiondirEditField.Value, solution_folder);
                if ~isfolder(solution_dir)
                    solution_dict = py.BEASY_IN_OUT1.get_response_data_for_IDs_and_input_parameters(py.list(app.parameters), app.solution_parameters, app.rootfolderEditField.Value, app.collectiondirEditField.Value,  py.list(app.calibration_data_type), py.list(app.IDs),  py.list(app.IDs_types));
                    solution_data = convert_pydict2data(solution_dict,1);
                else
                    simulation_files = dir(solution_dir);
                    files_name = simulation_files(end-1).name;
                    files_name = strsplit(files_name, '.');
                    files_name = files_name{1};
                    solution_dict = py.BEASY_IN_OUT1.get_output_data_for_IDs_from_simulation_folder(solution_dir, files_name, py.list(app.calibration_data_type),  py.list(app.IDs), py.list(app.IDs_types));
                    solution_data = convert_pydict2data(solution_dict,0);
                end
                
                
                IDs = {str2num(app.SurfacePotentialEditField.Value), str2num(app.NormalCurrentdensityEditField.Value)};
                %{
                disp_list = {IDs, solution_data, app.calibration_data };
                    
                for i =1:size(disp_list,2)
                    disp(disp_list{i});
                end
                %}
                %app.UIAxes.Projection = 'orthographic';
                cla(app.UIAxes,'reset');
                comparision_plot(app.UIAxes, IDs, solution_data, app.calibration_data, 'Comparison between solutions');
                
                %saveas(app.UIFigure, 'result_display.png');
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalibrationMethodDropDownLabel = uilabel(app.UIFigure);
            app.CalibrationMethodDropDownLabel.Text = 'Calibration Method';
            app.CalibrationMethodDropDown = uidropdown(app.UIFigure);
            app.CalibrationMethodDropDown.Items = {'gradient-based and simulator running', 'Surrogate assisted', 'ROM assisted'};
            app.CalibrationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationMethodDropDownValueChanged, true);
            app.CalibrationMethodDropDown.Value = 'gradient-based and simulator running';
            app.ResponseDataCountPanel = uipanel(app.UIFigure);
            app.ResponseDataCountPanel.Title = 'Response Data Count';
            app.SurfacePotentialSpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.SurfacePotentialSpinnerLabel.Text = 'Surface Potential';
            app.SurfacePotentialSpinner = uispinner(app.ResponseDataCountPanel);
            app.SurfacePotentialSpinner.ValueChangedFcn = createCallbackFcn(app, @SurfacePotentialSpinnerValueChanged, true);
            app.NormalCurrentdensitySpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.NormalCurrentdensitySpinnerLabel.Text = 'Normal Current density';
            app.NormalCurrentdensitySpinner = uispinner(app.ResponseDataCountPanel);
            app.NormalCurrentdensitySpinner.Limits = [0 100];
            app.NormalCurrentdensitySpinner.ValueChangedFcn = createCallbackFcn(app, @NormalCurrentdensitySpinnerValueChanged, true);
            app.NormalCurrentdensitySpinner.Enable = 'off';
            app.ElectricFieldSpinnerLabel = uilabel(app.ResponseDataCountPanel);
            app.ElectricFieldSpinnerLabel.Text = 'Electric Field';
            app.ElectricFieldSpinner = uispinner(app.ResponseDataCountPanel);
            app.ElectricFieldSpinner.Limits = [0 100];
            app.ElectricFieldSpinner.ValueChangedFcn = createCallbackFcn(app, @ElectricFieldSpinnerValueChanged, true);
            app.ElectricFieldSpinner.Enable = 'off';
            app.ResponsedataIDsPanel = uipanel(app.UIFigure);
            app.ResponsedataIDsPanel.Title = 'Response data IDs';
            app.SurfacePotentialEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.SurfacePotentialEditFieldLabel.Text = 'Surface Potential';
            app.SurfacePotentialEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.SurfacePotentialEditField.ValueChangedFcn = createCallbackFcn(app, @SurfacePotentialEditFieldValueChanged, true);
            app.SurfacePotentialEditField.Enable = 'off';
            app.NormalCurrentdensityEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.NormalCurrentdensityEditFieldLabel.Text = 'Normal Current density';
            app.NormalCurrentdensityEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.NormalCurrentdensityEditField.ValueChangedFcn = createCallbackFcn(app, @NormalCurrentdensityEditFieldValueChanged, true);
            app.NormalCurrentdensityEditField.Enable = 'off';
            app.ElectricFieldEditFieldLabel = uilabel(app.ResponsedataIDsPanel);
            app.ElectricFieldEditFieldLabel.Text = 'Electric Field';
            app.ElectricFieldEditField = uieditfield(app.ResponsedataIDsPanel, 'text');
            app.ElectricFieldEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricFieldEditFieldValueChanged, true);
            app.ElectricFieldEditField.Enable = 'off';
            app.ParametersforcalibrationPanel = uipanel(app.UIFigure);
            app.ParametersforcalibrationPanel.Title = 'Parameters for calibration';
            app.polarisationcurvepvalueEditFieldLabel = uilabel(app.ParametersforcalibrationPanel);
            app.polarisationcurvepvalueEditFieldLabel.Text = 'polarisation curve p-value ';
            app.polarisationcurvepvalueEditField = uieditfield(app.ParametersforcalibrationPanel, 'text');
            app.polarisationcurvepvalueEditField.ValueChangedFcn = createCallbackFcn(app, @polarisationcurvepvalueEditFieldValueChanged, true);
            app.ConductivityEditFieldLabel = uilabel(app.ParametersforcalibrationPanel);
            app.ConductivityEditFieldLabel.Text = 'Conductivity';
            app.ConductivityEditField = uieditfield(app.ParametersforcalibrationPanel, 'text');
            app.ConductivityEditField.ValueChangedFcn = createCallbackFcn(app, @ConductivityEditFieldValueChanged, true);
            app.BEASYinputoutputresourcesPanel = uipanel(app.UIFigure);
            app.BEASYinputoutputresourcesPanel.Title = 'BEASY input-output resources';
            app.rootfolderEditFieldLabel = uilabel(app.BEASYinputoutputresourcesPanel);
            app.rootfolderEditFieldLabel.Text = 'root folder';
            app.rootfolderEditField = uieditfield(app.BEASYinputoutputresourcesPanel, 'text');
            app.collectiondirEditFieldLabel = uilabel(app.BEASYinputoutputresourcesPanel);
            app.collectiondirEditFieldLabel.Text = 'collection dir';
            app.collectiondirEditField = uieditfield(app.BEASYinputoutputresourcesPanel, 'text');
            app.GraphDisplayDropDown_2Label = uilabel(app.UIFigure);
            app.GraphDisplayDropDown_2Label.Text = 'Graph Display';
            app.GraphDisplayDropDown_2 = uidropdown(app.UIFigure);
            app.GraphDisplayDropDown_2.Items = {'Structural model', 'Surrogate based graph', 'solution comparison'};
            app.GraphDisplayDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GraphDisplayDropDown_2ValueChanged, true);
            app.GraphDisplayDropDown_2.Value = 'Structural model';
            app.ParameterslowerrangeEditFieldLabel = uilabel(app.UIFigure);
            app.ParameterslowerrangeEditFieldLabel.Text = 'Parameters lower range';
            app.ParameterslowerrangeEditField = uieditfield(app.UIFigure, 'text');
            app.ParametersupperrangeEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersupperrangeEditFieldLabel.Text = 'Parameters upper range';
            app.ParametersupperrangeEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.CalibrationdataPanel = uipanel(app.UIFigure);
            app.CalibrationdataPanel.Title = 'Calibration data ';
            app.filepathEditFieldLabel = uilabel(app.CalibrationdataPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.CalibrationdataPanel, 'text');
            app.SolutionParametersEditFieldLabel = uilabel(app.UIFigure);
            app.SolutionParametersEditFieldLabel.Text = 'Solution Parameters';
            app.SolutionParametersEditField = uieditfield(app.UIFigure, 'text');
            app.SolutionParametersEditField.Editable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end